classdef RandomStudent_GUI_NewFormat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MainTab                      matlab.ui.container.Tab
        RandomStudentGeneratorLabel  matlab.ui.control.Label
        StudentNamesLabel            matlab.ui.control.Label
        NumberofCallsLabel           matlab.ui.control.Label
        NumberCorrectLabel           matlab.ui.control.Label
        PercentageCorrectLabel       matlab.ui.control.Label
        DropStudentButton            matlab.ui.control.Button
        AddStudentButton             matlab.ui.control.Button
        CallStudentButton_2          matlab.ui.control.Button
        AmountofStudentstoCallLabel  matlab.ui.control.Label
        ClassStatisticsPanel         matlab.ui.container.Panel
        Current_ClassLabel           matlab.ui.control.Label
        ClassCallsLabel              matlab.ui.control.Label
        AverageCallsperDayLabel      matlab.ui.control.Label
        PredictedCallsTodayLabel     matlab.ui.control.Label
        Current_class                matlab.ui.control.Label
        Class_calls                  matlab.ui.control.Label
        AverageCPD                   matlab.ui.control.Label
        Predicted_calls              matlab.ui.control.Label
        Gauge                        matlab.ui.control.LinearGauge
        ClassPercentCorrectLabel     matlab.ui.control.Label
        IncorrectButton              matlab.ui.control.Button
        SkipButton                   matlab.ui.control.Button
        CorrectButton                matlab.ui.control.Button
        LoadClassButton              matlab.ui.control.Button
        StudentName                  matlab.ui.control.Label
        Number_Calls                 matlab.ui.control.Label
        Number_correct               matlab.ui.control.Label
        Percent_correct              matlab.ui.control.Label
        Amount_students              matlab.ui.control.Label
        Tab2                         matlab.ui.container.Tab
    end


    properties (Access = public)
        courseInfo; % all data is stored in this structure
        filename; % to store the name of the file for loading/saving purposes
        randStudent; % to keep track of who is called
    end

    methods (Access = public)
    
         % function that updates the statistics in the GUI  
        function updateStats(app,n)
            % use nargin to allow for updating class stats on first load (call function without 'n')
            if nargin == 2
                % individual stats
                app.studentName.Text = app.courseInfo(n).names;
                
                nCorrect = app.courseInfo(n).numCorrect;
                app.numCorrect.Text = num2str(nCorrect);
                
                nCalls = app.courseInfo(n).calls;
                app.numCalls.Text = num2str(nCalls);
                
                % to avoid divide by 0 when calculating percentage
                if nCalls == 0
                    app.percentage.Text = 'N/A';
                else
                    percent = nCorrect / nCalls * 100;
                    app.percentage.Text = [num2str(percent) '%'];
                end
            end
                
            % class statistics
            totalCalls = 0;
            totalCorrect = 0;
            for i = 1:length(app.courseInfo)
                totalCalls = totalCalls + app.courseInfo(i).calls;
                totalCorrect = totalCorrect + app.courseInfo(i).numCorrect;
            end
            app.classCalls.Text = num2str(totalCalls);
            app.classCorrect.Text = num2str(totalCorrect);
            app.classPercentage.Text = [num2str(totalCorrect/totalCalls*100) '%'];
        end
        
       % button helpers - UX operations to help user visually assess what to do
        function callStudentButtonHelper(app)
            % enable / disable buttons
            app.callStudentButton.Enable = 'off';
            app.loadClassButton.Enable = 'off';
            app.CorrectButton.Enable = 'on';
            app.IncorrectButton.Enable = 'on';
            app.SkipButton.Enable = 'on';
            
            % change panel background colors
            app.classStatisticsPanel.BackgroundColor = 'white';
            app.answerPanel.BackgroundColor = 'white';
            app.individualStatisticsPanel.BackgroundColor = 'white';
            app.controlsPanel.BackgroundColor = [0.94 0.94 0.94];
        end
     
        function answerButtonHelper(app)
            % enable / disable buttons
            app.callStudentButton.Enable = 'on';
            app.loadClassButton.Enable = 'on';
            app.CorrectButton.Enable = 'off';
            app.IncorrectButton.Enable = 'off';
            app.SkipButton.Enable = 'off';    
            
            % change panel background colors
            app.answerPanel.BackgroundColor = [0.94 0.94 0.94];
            app.controlsPanel.BackgroundColor = 'white';
        end
    
        % save the mat file
        function saveData(app)
            courseInfo = app.courseInfo;
            file = app.filename;
            save(file,'courseInfo');
        end
        
    end


    methods (Access = private)

        % Button pushed function: CallStudentButton_2
        function CallStudentButton_2Pushed(app, event)
            % enable / disable buttons as now appropriate
            callStudentButtonHelper(app);
            app.studentName.FontColor = 'black';

            amount_student = 1;
            numStudents = length(app.courseInfo);
            population = 1:numStudents;
            probability = zeros(1,numStudents);
            for i = 1:length(app.courseInfo)
                probability(i) = app.courseInfo(i).probability;
            end
            
            randomStudent = randsample(population,amount_student,true,probability);
            app.randStudent = randomStudent;
            
            updateStats(app,randomStudent);
            app.courseInfo(randomStudent).calls = app.courseInfo(randomStudent).calls + 1;
            
        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            answerButtonHelper(app);
            app.courseInfo(app.randStudent).numCorrect = app.courseInfo(app.randStudent).numCorrect + 1;
            app.courseInfo(app.randStudent).probability = app.courseInfo(app.randStudent).probability * 0.5; % 50% less likely
            saveData(app);
            updateStats(app,app.randStudent);
        end

        % Button pushed function: IncorrectButton
        function IncorrectButtonPushed(app, event)
            answerButtonHelper(app);
            app.courseInfo(app.randStudent).probability = app.courseInfo(app.randStudent).probability * 1.1; % 10% more likely
            saveData(app);
            updateStats(app,app.randStudent);
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
             answerButtonHelper(app);
            app.courseInfo(app.randStudent).calls = app.courseInfo(app.randStudent).calls - 1; % was already added when called, so need to remedy
            saveData(app);
            updateStats(app,app.randStudent);
        end

        % Button pushed function: AddStudentButton
        function AddStudentButtonPushed(app, event)
            amount_student = 1;
            amount_student = amount_student + 1;
        end

        % Button pushed function: DropStudentButton
        function DropStudentButtonPushed(app, event)
            if amount_student > 1
            amount_student = amount_student - 1;
            else 
                error('One student minimum')
            end
        end

        % Button pushed function: LoadClassButton
        function LoadClassButtonPushed(app, event)
            % !!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!! %
            file = uigetfile('*.mat'); % FILE MUST CONTAIN STRUCTURE
            app.filename = file;
            load(file);
            app.courseInfo = courseInfo; % STRUCTURE MUST BE NAMED 'courseInfo'
            % courseInfo must have fields = {courseName, names, calls, numCorrect, probability};
            % !!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!! %
            
            app.Current_Class.Text = app.courseInfo(1).courseName;
            app.callStudentButton.Enable = 'on';
            app.studentName.FontColor = [0.6353 0.8039 0.6353];
            app.studentName.Text = 'Call student to begin';
            
            app.classStatisticsPanel.BackgroundColor = 'white';
            
            
            updateStats(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.RandomStudentGeneratorLabel = uilabel(app.MainTab);
            app.RandomStudentGeneratorLabel.Text = 'Random Student Generator ';
            app.StudentNamesLabel = uilabel(app.MainTab);
            app.StudentNamesLabel.Text = 'Student Name(s): ';
            app.NumberofCallsLabel = uilabel(app.MainTab);
            app.NumberofCallsLabel.Text = 'Number of Calls:';
            app.NumberCorrectLabel = uilabel(app.MainTab);
            app.NumberCorrectLabel.Text = 'Number Correct:';
            app.PercentageCorrectLabel = uilabel(app.MainTab);
            app.PercentageCorrectLabel.Text = 'Percentage Correct:';
            app.DropStudentButton = uibutton(app.MainTab, 'push');
            app.DropStudentButton.ButtonPushedFcn = createCallbackFcn(app, @DropStudentButtonPushed, true);
            app.DropStudentButton.Icon = 'deleted_user.png';
            app.DropStudentButton.Text = 'Drop Student';
            app.AddStudentButton = uibutton(app.MainTab, 'push');
            app.AddStudentButton.ButtonPushedFcn = createCallbackFcn(app, @AddStudentButtonPushed, true);
            app.AddStudentButton.Icon = 'add_user.png';
            app.AddStudentButton.Text = 'Add Student';
            app.CallStudentButton_2 = uibutton(app.MainTab, 'push');
            app.CallStudentButton_2.ButtonPushedFcn = createCallbackFcn(app, @CallStudentButton_2Pushed, true);
            app.CallStudentButton_2.Icon = 'phone.png';
            app.CallStudentButton_2.Text = 'Call Student';
            app.AmountofStudentstoCallLabel = uilabel(app.MainTab);
            app.AmountofStudentstoCallLabel.Text = 'Amount of Students to Call: ';
            app.ClassStatisticsPanel = uipanel(app.MainTab);
            app.ClassStatisticsPanel.Title = 'Class Statistics';
            app.Current_ClassLabel = uilabel(app.ClassStatisticsPanel);
            app.Current_ClassLabel.Text = 'Current_Class:';
            app.ClassCallsLabel = uilabel(app.ClassStatisticsPanel);
            app.ClassCallsLabel.Text = 'Class Calls:';
            app.AverageCallsperDayLabel = uilabel(app.ClassStatisticsPanel);
            app.AverageCallsperDayLabel.Text = 'Average Calls per Day:';
            app.PredictedCallsTodayLabel = uilabel(app.ClassStatisticsPanel);
            app.PredictedCallsTodayLabel.Text = 'Predicted Calls Today:';
            app.Current_class = uilabel(app.ClassStatisticsPanel);
            app.Current_class.Text = '';
            app.Class_calls = uilabel(app.ClassStatisticsPanel);
            app.Class_calls.Text = '';
            app.AverageCPD = uilabel(app.ClassStatisticsPanel);
            app.AverageCPD.Text = '';
            app.Predicted_calls = uilabel(app.ClassStatisticsPanel);
            app.Predicted_calls.Text = '';
            app.Gauge = uigauge(app.MainTab, 'linear');
            app.ClassPercentCorrectLabel = uilabel(app.MainTab);
            app.ClassPercentCorrectLabel.Text = 'Class Percent Correct';
            app.IncorrectButton = uibutton(app.MainTab, 'push');
            app.IncorrectButton.ButtonPushedFcn = createCallbackFcn(app, @IncorrectButtonPushed, true);
            app.IncorrectButton.Icon = 'circle-cross.png';
            app.IncorrectButton.Text = 'Incorrect';
            app.SkipButton = uibutton(app.MainTab, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Icon = 'skip-forward.png';
            app.SkipButton.Text = 'Skip';
            app.CorrectButton = uibutton(app.MainTab, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Icon = 'circle-check.png';
            app.CorrectButton.Text = 'Correct';
            app.LoadClassButton = uibutton(app.MainTab, 'push');
            app.LoadClassButton.ButtonPushedFcn = createCallbackFcn(app, @LoadClassButtonPushed, true);
            app.LoadClassButton.Icon = 'truck-load-512.png';
            app.LoadClassButton.Text = 'Load Class';
            app.StudentName = uilabel(app.MainTab);
            app.StudentName.Text = '';
            app.Number_Calls = uilabel(app.MainTab);
            app.Number_Calls.Text = '';
            app.Number_correct = uilabel(app.MainTab);
            app.Number_correct.Text = '';
            app.Percent_correct = uilabel(app.MainTab);
            app.Percent_correct.Text = '';
            app.Amount_students = uilabel(app.MainTab);
            app.Amount_students.Text = '';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RandomStudent_GUI_NewFormat

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end