classdef randomStudentGUI_beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RandomStudentGUIUIFigure       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        answerPanel                    matlab.ui.container.Panel
        CorrectButton                  matlab.ui.control.Button
        SkipButton                     matlab.ui.control.Button
        IncorrectButton                matlab.ui.control.Button
        classStatisticsPanel           matlab.ui.container.Panel
        classCallsLabel                matlab.ui.control.Label
        classCorrectLabel              matlab.ui.control.Label
        classPercentageLabel           matlab.ui.control.Label
        classCalls                     matlab.ui.control.Label
        classCorrect                   matlab.ui.control.Label
        classPercentage                matlab.ui.control.Label
        CurrentClassLabel              matlab.ui.control.Label
        CurrentClass                   matlab.ui.control.Label
        individualStatisticsPanel      matlab.ui.container.Panel
        InfoTable                      matlab.ui.control.Table
        numCalls                       matlab.ui.control.Label
        percentage                     matlab.ui.control.Label
        numCorrect                     matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        TheluckyonesLabel              matlab.ui.control.Label
        controlsPanel                  matlab.ui.container.Panel
        callStudentButton              matlab.ui.control.Button
        loadClassButton                matlab.ui.control.Button
        HowmanystudentsEditFieldLabel  matlab.ui.control.Label
        HowmanystudentsEditField       matlab.ui.control.NumericEditField
        DrBsRandomStudentGUILabel      matlab.ui.control.Label
        studentName                    matlab.ui.control.Label
        GraphsTab                      matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        PredictonsTab                  matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        DrBwillaskthismanyquestionstodayLabel  matlab.ui.control.Label
        MAGIC8BALLLabel                matlab.ui.control.Label
        InitializeTab                  matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
    end


    properties (Access = public)
        courseInfo; % all data is stored in this structure
        filename; % to store the name of the file for loading/saving purposes
        randStudent; % to keep track of who is called
    end

    methods (Access = public)
    
        % function that updates the statistics in the GUI  
        function updateStats(app,n)
            % use nargin to allow for updating class stats on first load (call function without 'n')
            if nargin == 2
                % individual stats
                app.studentName.Text = app.courseInfo(n).names;
                Name = app.studentName.Text;
                
                nCorrect = app.courseInfo(n).numCorrect;
                app.numCorrect.Text = num2str(nCorrect);
                
                nCalls = app.courseInfo(n).calls;
                app.numCalls.Text = num2str(nCalls);
                
                % to avoid divide by 0 when calculating percentage
                if nCalls == 0
                    app.percentage.Text = 'N/A';
                else
                    percent = nCorrect / nCalls * 100;
                    app.percentage.Text = [num2str(percent) '%'];
                end
            end
                
            % class statistics
            totalCalls = 0;
            totalCorrect = 0;
            for i = 1:length(app.courseInfo)
                totalCalls = totalCalls + app.courseInfo(i).calls;
                totalCorrect = totalCorrect + app.courseInfo(i).numCorrect;
            end
            app.classCalls.Text = num2str(totalCalls);
            app.classCorrect.Text = num2str(totalCorrect);
            app.classPercentage.Text = [num2str(totalCorrect/totalCalls*100) '%'];
        end
        
       % button helpers - UX operations to help user visually assess what to do
        function callStudentButtonHelper(app)
            % enable / disable buttons
            app.callStudentButton.Enable = 'off';
            app.loadClassButton.Enable = 'off';
            app.CorrectButton.Enable = 'on';
            app.IncorrectButton.Enable = 'on';
            app.SkipButton.Enable = 'on';
            
            % change panel background colors
            app.classStatisticsPanel.BackgroundColor = 'white';
            app.answerPanel.BackgroundColor = 'white';
            app.individualStatisticsPanel.BackgroundColor = 'white';
            app.controlsPanel.BackgroundColor = [0.94 0.94 0.94];
        end
     
        function answerButtonHelper(app)
            % enable / disable buttons
            app.callStudentButton.Enable = 'on';
            app.loadClassButton.Enable = 'on';
            app.CorrectButton.Enable = 'off';
            app.IncorrectButton.Enable = 'off';
            app.SkipButton.Enable = 'off';    
            
            % change panel background colors
            app.answerPanel.BackgroundColor = [0.94 0.94 0.94];
            app.controlsPanel.BackgroundColor = 'white';
        end
    
        % save the mat file
        function saveData(app)
            courseInfo = app.courseInfo;
            file = app.filename;
            save(file,'courseInfo');
        end
        
    end


    methods (Access = private)

        % Button pushed function: loadClassButton
        function loadClassButtonPushed(app, event)
            % !!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!! %
            file = uigetfile('*.mat'); % FILE MUST CONTAIN STRUCTURE
            app.filename = file;
            load(file);
            app.courseInfo = courseInfo; % STRUCTURE MUST BE NAMED 'courseInfo'
            % courseInfo must have fields = {courseName, names, calls, numCorrect, probability};
            % !!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!! %
            
            app.CurrentClass.Text = app.courseInfo(1).courseName;
            app.callStudentButton.Enable = 'on';
            app.studentName.FontColor = [0.6353 0.8039 0.6353];
            app.studentName.Text = 'Call student to begin';
            
            app.classStatisticsPanel.BackgroundColor = 'white';
            
            
            updateStats(app);
        end

        % Callback function: HowmanystudentsEditField, ListBox, 
        % callStudentButton
        function callStudentButtonPushed(app, event)
            % enable / disable buttons as now appropriate
            callStudentButtonHelper(app);
            
            NumStu = app.HowmanystudentsEditField.Value;
            app.HowmanystudentsEditField.Value = NumStu;
            

            numStudents = length(app.courseInfo);
            population = 1:numStudents;
            probability = zeros(1,numStudents);
            for i = 1:length(app.courseInfo)
                probability(i) = app.courseInfo(i).probability;
            end
            
            randomStudent = randsample(population,NumStu,true,probability);
            app.randStudent = randomStudent;
            
            updateStats(app,randomStudent);
            for i = 1:length(randomStudent)
                j = randomStudent(1,i);
                app.courseInfo(j).calls = app.courseInfo(j).calls + 1;
            end
            
            
        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            answerButtonHelper(app);
                        for i = 1:length(randomStudent)
                j = randomStudent(1,i);
            app.courseInfo(app.j).numCorrect = app.courseInfo(app.j).numCorrect + 1;
            app.courseInfo(app.j).probability = app.courseInfo(app.j).probability * 0.5; % 50% less likely
            saveData(app);
            updateStats(app,app.j);
            end

        end

        % Button pushed function: IncorrectButton
        function IncorrectButtonPushed(app, event)
            answerButtonHelper(app);
            app.courseInfo(app.randStudent).probability = app.courseInfo(app.randStudent).probability * 1.1; % 10% more likely
            saveData(app);
            updateStats(app,app.randStudent);
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            answerButtonHelper(app);
            app.courseInfo(app.randStudent).calls = app.courseInfo(app.randStudent).calls - 1; % was already added when called, so need to remedy
            saveData(app);
            updateStats(app,app.randStudent);
        end

        % Callback function
        function ExtrememodeKnobValueChanged(app, event)

        end

        % Callback function
        function HowmanystudentsEditFieldValueChanged(app, event)
            value = app.HowmanystudentsEditField.Value;
            
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            app.ListBox = uilistbox(app.individualStatisticsPanel);
            app.ListBox.Items = {app.courseInfo(n).names, 'Name', '2', '3', '4', '5'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.FontSize = 16;
            app.ListBox.Position = [154 121 323 113];
            app.ListBox.Value = 'Name';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RandomStudentGUIUIFigure = uifigure;
            app.RandomStudentGUIUIFigure.Name = 'Random Student GUI';
            app.TabGroup = uitabgroup(app.RandomStudentGUIUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.answerPanel = uipanel(app.MainTab);
            app.answerPanel.Title = '2) answer';
            app.CorrectButton = uibutton(app.answerPanel, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Enable = 'off';
            app.CorrectButton.Text = 'Correct';
            app.SkipButton = uibutton(app.answerPanel, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Enable = 'off';
            app.SkipButton.Text = 'Skip';
            app.IncorrectButton = uibutton(app.answerPanel, 'push');
            app.IncorrectButton.ButtonPushedFcn = createCallbackFcn(app, @IncorrectButtonPushed, true);
            app.IncorrectButton.Enable = 'off';
            app.IncorrectButton.Text = 'Incorrect';
            app.classStatisticsPanel = uipanel(app.MainTab);
            app.classStatisticsPanel.Title = 'classStatistics';
            app.classCallsLabel = uilabel(app.classStatisticsPanel);
            app.classCallsLabel.Text = 'classCalls:';
            app.classCorrectLabel = uilabel(app.classStatisticsPanel);
            app.classCorrectLabel.Text = 'classCorrect:';
            app.classPercentageLabel = uilabel(app.classStatisticsPanel);
            app.classPercentageLabel.Text = 'classPercentage:';
            app.classCalls = uilabel(app.classStatisticsPanel);
            app.classCalls.Text = '';
            app.classCorrect = uilabel(app.classStatisticsPanel);
            app.classCorrect.Text = '';
            app.classPercentage = uilabel(app.classStatisticsPanel);
            app.classPercentage.Text = '';
            app.CurrentClassLabel = uilabel(app.classStatisticsPanel);
            app.CurrentClassLabel.Text = 'Current Class:';
            app.CurrentClass = uilabel(app.classStatisticsPanel);
            app.CurrentClass.Text = '';
            app.individualStatisticsPanel = uipanel(app.MainTab);
            app.individualStatisticsPanel.Title = 'individualStatistics';
            app.InfoTable = uitable(app.individualStatisticsPanel);
            app.InfoTable.ColumnName = {'NumCalls'; 'NumCorrect'; 'Percentage'};
            app.InfoTable.RowName = {};
            app.numCalls = uilabel(app.individualStatisticsPanel);
            app.numCalls.Text = '';
            app.percentage = uilabel(app.individualStatisticsPanel);
            app.percentage.Text = '';
            app.numCorrect = uilabel(app.individualStatisticsPanel);
            app.numCorrect.Text = '';
            app.ListBox = uilistbox(app.individualStatisticsPanel);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @callStudentButtonPushed, true);
            app.TheluckyonesLabel = uilabel(app.individualStatisticsPanel);
            app.TheluckyonesLabel.Text = 'The lucky one(s)';
            app.controlsPanel = uipanel(app.MainTab);
            app.controlsPanel.Title = '1) controls';
            app.callStudentButton = uibutton(app.controlsPanel, 'push');
            app.callStudentButton.ButtonPushedFcn = createCallbackFcn(app, @callStudentButtonPushed, true);
            app.callStudentButton.Enable = 'off';
            app.callStudentButton.Text = 'callStudent';
            app.loadClassButton = uibutton(app.controlsPanel, 'push');
            app.loadClassButton.ButtonPushedFcn = createCallbackFcn(app, @loadClassButtonPushed, true);
            app.loadClassButton.Text = 'loadClass';
            app.HowmanystudentsEditFieldLabel = uilabel(app.controlsPanel);
            app.HowmanystudentsEditFieldLabel.Text = 'How many students?';
            app.HowmanystudentsEditField = uieditfield(app.controlsPanel, 'numeric');
            app.HowmanystudentsEditField.Limits = [1 500];
            app.HowmanystudentsEditField.ValueDisplayFormat = '%.0f';
            app.HowmanystudentsEditField.ValueChangedFcn = createCallbackFcn(app, @callStudentButtonPushed, true);
            app.HowmanystudentsEditField.Value = 1;
            app.DrBsRandomStudentGUILabel = uilabel(app.MainTab);
            app.DrBsRandomStudentGUILabel.Text = 'Dr.B''s Random Student GUI';
            app.studentName = uilabel(app.MainTab);
            app.studentName.Text = 'loadClass to begin';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes = uiaxes(app.GraphsTab);
            title(app.UIAxes, 'Historgram - Time of Class Asked Question')
            xlabel(app.UIAxes, '5 minute intervals from start of class')
            ylabel(app.UIAxes, '# of questions asked at that time')
            app.UIAxes_2 = uiaxes(app.GraphsTab);
            title(app.UIAxes_2, 'Histrogram - Duration Until Answered')
            xlabel(app.UIAxes_2, '10 second intervals after callStudent press')
            ylabel(app.UIAxes_2, '# of questions asked at that time')
            app.PredictonsTab = uitab(app.TabGroup);
            app.PredictonsTab.Title = 'Predictons';
            app.UIAxes2 = uiaxes(app.PredictonsTab);
            title(app.UIAxes2, 'Historical Data on Time Question is Asked')
            xlabel(app.UIAxes2, 'days since start of semester')
            ylabel(app.UIAxes2, '# of questions asked on that day')
            app.DrBwillaskthismanyquestionstodayLabel = uilabel(app.PredictonsTab);
            app.DrBwillaskthismanyquestionstodayLabel.Text = 'Dr. B will ask this many questions today:';
            app.MAGIC8BALLLabel = uilabel(app.PredictonsTab);
            app.MAGIC8BALLLabel.Text = 'MAGIC 8 BALL';
            app.InitializeTab = uitab(app.TabGroup);
            app.InitializeTab.Title = 'Initialize';
            app.Label = uilabel(app.InitializeTab);
            app.Label.Text = {'COMING SOON: Select your XLS file and automatically generate structure and'; ' add to path for easy use'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = randomStudentGUI_beta

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RandomStudentGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RandomStudentGUIUIFigure)
        end
    end
end